classdef piano1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PianoUIFigure            matlab.ui.Figure
        C4                       matlab.ui.control.Button
        D4                       matlab.ui.control.Button
        E4                       matlab.ui.control.Button
        F4                       matlab.ui.control.Button
        G4                       matlab.ui.control.Button
        A4                       matlab.ui.control.Button
        B4                       matlab.ui.control.Button
        FSharp4                  matlab.ui.control.Button
        ASharp4                  matlab.ui.control.Button
        GSharp4                  matlab.ui.control.Button
        DSharp4                  matlab.ui.control.Button
        CSharp4                  matlab.ui.control.Button
        soundAxes                matlab.ui.control.UIAxes
        frequencySlider          matlab.ui.control.Slider
        envelopeAxes             matlab.ui.control.UIAxes
        FrequencyHzLabel         matlab.ui.control.Label
        freqValue                matlab.ui.control.Label
        AttackEditFieldLabel     matlab.ui.control.Label
        attackValue              matlab.ui.control.NumericEditField
        DecayEditFieldLabel      matlab.ui.control.Label
        decayValue               matlab.ui.control.NumericEditField
        SustainEditFieldLabel    matlab.ui.control.Label
        sustainValue             matlab.ui.control.NumericEditField
        ReleaseEditFieldLabel    matlab.ui.control.Label
        releaseValue             matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        AmplitudeADSRenvelopeControlerLabel  matlab.ui.control.Label
        SwitchtoKeyboardButton   matlab.ui.control.Button
        NoteButtonGroup          matlab.ui.container.ButtonGroup
        NoteButton               matlab.ui.control.RadioButton
        NoteButton_2             matlab.ui.control.RadioButton
        NoteButton_3             matlab.ui.control.RadioButton
        envelopeAxes_2           matlab.ui.control.UIAxes
        Label_6                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        attackValue_2            matlab.ui.control.NumericEditField
        decayValue_2             matlab.ui.control.NumericEditField
        sustainValue_2           matlab.ui.control.NumericEditField
        releaseValue_2           matlab.ui.control.NumericEditField
        FrequencyADSRenvelopeControlerLabel  matlab.ui.control.Label
        attackKnob               matlab.ui.control.Knob
        decayKnob                matlab.ui.control.Knob
        sustainKnob              matlab.ui.control.Knob
        releaseKnob              matlab.ui.control.Knob
        attackKnob_2             matlab.ui.control.Knob
        decayKnob_2              matlab.ui.control.Knob
        sustainKnob_2            matlab.ui.control.Knob
        releaseKnob_2            matlab.ui.control.Knob
        AttackEditFieldLabel_3   matlab.ui.control.Label
        DecayEditFieldLabel_3    matlab.ui.control.Label
        SustainEditFieldLabel_3  matlab.ui.control.Label
        ReleaseEditFieldLabel_3  matlab.ui.control.Label
        AnimatedCheckBox         matlab.ui.control.CheckBox
        RECORDButton             matlab.ui.control.StateButton
        HighPassKnob             matlab.ui.control.Knob
        LowPassKnob              matlab.ui.control.Knob
        highPass                 matlab.ui.control.NumericEditField
        lowPass                  matlab.ui.control.NumericEditField
        HzLabel                  matlab.ui.control.Label
        HzLabel_2                matlab.ui.control.Label
        lolabel                  matlab.ui.control.Label
        hilabel                  matlab.ui.control.Label
        WaveButtonGroup          matlab.ui.container.ButtonGroup
        sinWave                  matlab.ui.control.RadioButton
        SawWave                  matlab.ui.control.RadioButton
        SquareWave               matlab.ui.control.RadioButton
        FileNameEditFieldLabel   matlab.ui.control.Label
        FileNameEditField        matlab.ui.control.EditField
        PLAYButton               matlab.ui.control.StateButton
        highpassSlider           matlab.ui.control.Slider
        lowpassSlider            matlab.ui.control.Slider
        highpassAxes             matlab.ui.control.UIAxes
        lowpassAxes              matlab.ui.control.UIAxes
        stopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        PitchDetectionLabel      matlab.ui.control.Label
        NoteLabel                matlab.ui.control.Label
        NoteChar                 matlab.ui.control.Label
        AlgorithimLabel          matlab.ui.control.Label
        AlgorithimDropDown       matlab.ui.control.DropDown
        EchoCheckBox             matlab.ui.control.CheckBox
        alphaSpinnerLabel        matlab.ui.control.Label
        alphaSpinner             matlab.ui.control.Spinner
        MidiFileCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        record=zeros(1,100); % Recorded notes
        time=0;
        noteIndex=1;
        sampleRate= 44000;
        notes_char=["A","A#","B","C","C#","D","D#","E","F","F#","G","G#"];
        notes_freq=[55,58.3,61.7,32.7,34.6,36.7,38.9,41.2,43.7,46.2,49.0,51.9];
        stop=0;
    end
    
    methods (Access = private)
        
        function PlayNote(app,freq)

            if app.RECORDButton.Value==1 
                if app.noteIndex<=100
                   app.record(1,app.noteIndex)=freq;
                   app.noteIndex=app.noteIndex+1;
                end
            end
            
   
            %note sample rate
            SampleRate= app.sampleRate;
            
            %duration of the note sound
            TimeValue=60/172;
            
            %if it is a full note multiply by 4,if it's half multiply be 2
            if app.NoteButton_2.Value==1 
                TimeValue=TimeValue*2;
            
            elseif app.NoteButton_3.Value==1
                TimeValue=TimeValue*4;
            end 
            
            app.time=TimeValue;
            
            %sound duration vector
            Samples= 0:1/SampleRate:TimeValue;
            %frequncy vector
            freqVector=freq*ones(1,length(Samples));
           % x =  sin(2*pi*freq.*Samples);
         
             %------------------------------ calculate the frequency envelope ------------------------------------%
             
            %get the ADSR percentage values
            attack=app.attackValue_2.Value/100;
            decay=app.decayValue_2.Value/100;
            sustain=app.sustainValue_2.Value/100;
            release=app.releaseValue_2.Value/100;
            sum2=attack+decay+sustain+release;
            
            %if the input is wrong declare with default values
            if sum2~=1 
                attack=0;
                app.attackValue_2.Value=attack*100;
                decay=0.;
                app.decayValue_2.Value=decay*100;
                sustain=1;
                app.sustainValue_2.Value=sustain*100;
                release=0;
                app.releaseValue_2.Value=release*100;
            end
            
            %declare the ADSR envelelop values
            len=length(freqVector);   
            A2 = linspace(0, 1, attack*len);        
            D2 = linspace(1, 0.8, decay*len); 
            S2 = linspace(0.8, 0.8, sustain*len);
            R2 = linspace(0.8, 0, release*len); 
            
            %ADSR vector
            ADSR2 =[A2 D2 S2 R2];
            feqEnvelope=zeros(size(freqVector));
            feqEnvelope(1:length(ADSR2)) = ADSR2;
            
            %multiply- envelopes the sound vector accordingly
            envelopedFrequncy=freqVector.*feqEnvelope;
            if sustain==1 
                 envelopedFrequncy=freqVector;
            end
             %------------------------------ calculate the frequency envelope ------------------------------------%

            
            %declare the sound vector using the note sound function (sinusoid/square/sawtooth)
            
            if app.sinWave.Value == 1
                  soundVector =  sin(2*pi*envelopedFrequncy.*Samples);
            elseif app.SquareWave.Value == 1
                  soundVector =  square(2*pi*envelopedFrequncy.*Samples);
            elseif app.SawWave.Value == 1
                  soundVector =  sawtooth(2*pi*envelopedFrequncy.*Samples);
            end
            
            if app.highPass.Value>0
                Wn=app.highPass.Value/SampleRate;
                n=round(app.highpassSlider.Value);
                app.highpassSlider.Value=n;
                [b,a] = butter(n,Wn,'high');
                soundVector=filter(b,a,soundVector);
                [H,W]=freqz(b,a);
                plot(app.highpassAxes,W,H);
            end
            if app.lowPass.Value>0
                Wn=app.lowPass.Value/SampleRate;
                n=round(app.lowpassSlider.Value);
                app.lowpassSlider.Value=n;
                [b,a] = butter(n,Wn,'low');
                soundVector=filter(b,a,soundVector);
                [H,W]=freqz(b,a);
                plot(app.lowpassAxes,W,H);
            end

            %------------------------------ calculate the amplitude envelope ------------------------------------%
            %get the ADSR percentage values
            attack=app.attackValue.Value/100;
            decay=app.decayValue.Value/100;
            sustain=app.sustainValue.Value/100;
            release=app.releaseValue.Value/100;
            sum=attack+decay+sustain+release;
            
            %if the input is wrong declare with default values
            if sum~=1 
                attack=0.1;
                app.attackValue.Value=attack*100;
                decay=0.15;
                app.decayValue.Value=decay*100;
                sustain=0.60;
                app.sustainValue.Value=sustain*100;
                release=0.15;
                app.releaseValue.Value=release*100;
            end
 
            %declare the ADSR envelelop values
            len=length(soundVector);   
            A = linspace(0, 1, attack*len);        
            D = linspace(1, 0.8, decay*len); 
            S = linspace(0.8, 0.8, sustain*len);
            R = linspace(0.8, 0, release*len); 
            
            %ADSR vector
            ADSR =[A D S R];
            ampEnvelope=zeros(size(soundVector));
            ampEnvelope(1:length(ADSR)) = ADSR;
            
            %multiply- envelopes the sound vector accordingly
            soundVector=soundVector.*ampEnvelope;
            
             %------------------------------ calculate the amplitude envelope ------------------------------------%
            
            %plot the amplitude envelope figure
            plot(app.envelopeAxes,Samples,ampEnvelope);
            %plot the frequency envelope figure
            plot(app.envelopeAxes_2,Samples,feqEnvelope);

            % add echo if checked
            if app.EchoCheckBox.Value==1
                timelag = 0.25;
                delta = round(SampleRate*timelag);
                alpha = app.alphaSpinner.Value;
                orig = [soundVector';zeros(delta,1)];
                echo = [zeros(delta,1);soundVector']*alpha;
                mtEcho = orig + echo;
                soundsc(mtEcho,SampleRate);
            else 
                %play the note sound without echo
                soundsc(soundVector,SampleRate);
            end 
            if app.AnimatedCheckBox.Value== 1
                     animate(app,Samples,soundVector);
            else
                plot(app.soundAxes,Samples,soundVector);
            end
            
             %------------------------------ Pitch Detection ------------------------------------%
             f0=0;
             x=soundVector;
             if app.AlgorithimDropDown.Value=="Zero-Crossing"
                 f0 = Zero_Crossing(app,x,SampleRate);
             elseif app.AlgorithimDropDown.Value=="Fast Fourier Transform"
                 f0=FFT(app,x,SampleRate);
             elseif app.AlgorithimDropDown.Value=="Autocorrelation"
                 f0=Autocorrelation(app,x,SampleRate);
             elseif app.AlgorithimDropDown.Value=="Cross Correlation"
                 f0=Cross_Correlation(app,x,SampleRate);
             elseif app.AlgorithimDropDown.Value=="Average Magnitude Differential"
                 f0=AverageMagnitudeDifferential(app,x,SampleRate);                 
             end
              
             % Display the detected frequency and note %
             DetectNote(app,f0);
        
             %------------------------------ Pitch Detection ------------------------------------%
        end
        
        function animate(app,x,y)
            cla(app.soundAxes);
            curve=animatedline(app.soundAxes,'color','w');
            for i=1:length(x)
                addpoints(curve,x(i),y(i)); 
                drawnow limitrate
            end
        end
        
        function [y,Fs]=midi2audio(app,input,Fs)
            % y = midi2audio(input, Fs)
            % y = midi2audio(input)
            %
            % Convert midi structure to a digital waveform
            %
            % Inputs:
            %  input - can be one of:
            %    a structure: matlab midi structure (created by readmidi.m)
            %    a string: a midi filename
            %    other: a 'Notes' matrix (as ouput by midiInfo.m)
            %
            %  synthtype - string to choose synthesis method
            %      passed to synth function in synth.m
            %      current choices are: 'fm', 'sine' or 'saw'
            %      default='fm'
            %
            %  Fs - sampling frequency in Hz (beware of aliasing!)
            %       default =  44.1e3
            
            % Copyright (c) 2009 Ken Schutte
            % more info at: http://www.kenschutte.com/midi
         
               
            if nargin<3
              Fs=44.1e3;
            end
            endtime = -1;
            if  isstruct(input) 
              [Notes,endtime] = midiInfo(input,0);
            elseif  ischar(input) 
              [Notes,endtime] = midiInfo(readmidi(input), 0);
            else
              Notes = input;
            end
            
            % 6th col= the end time of note
            if  endtime == -1 
              endtime = max(Notes(:,6));
            end
            %if we get more than one max end time we take the maximum
            if  length(endtime)>1 
              endtime = max(endtime);
            end

            y = zeros(1,ceil(endtime*Fs));
            
            for i=1:size(Notes,1)
              %get the freq value using the midi2freq method, the 3rd col in Notes is the note number
              f = midi2freq(Notes(i,3));
              %duration of the note, col 5=start time, col 6=end time-> duration=col6 value-col5 value
              dur = Notes(i,6) - Notes(i,5);
              %col 4=velocity
              amp = Notes(i,4)/127;
            
              yt = synth(app,f, dur, amp, Fs);
                
              n1 = floor(Notes(i,5)*Fs)+1;
              N = length(yt);  
            
              n2 = n1 + N - 1;
              
              % hack: for some examples (6246525.midi), one yt
              %       extended past endtime (just by one sample in this case)
              % todo: check why that was the case.  For now, just truncate,
              if  n2 > length(y)
                ndiff = n2 - length(y);
                % 
                yt = yt(1:(end-ndiff));
                n2 = n2 - ndiff;
              end
            
              % ensure yt is [1,N]:
              y(n1:n2) = y(n1:n2) + reshape(yt,1,[]);
             
              pause(1/Fs);
            end
        end
    end
   
    
    methods (Access = public)
        
        function y=synth(app,freq,dur,amp,Fs)
            % y=synth(freq,dur,amp,Fs,type)
        %
        % Synthesize a single note
        %
        % Inputs:
        %  freq - frequency in Hz
        %  dur - duration in seconds
        %  amp - Amplitude in range [0,1]
        %  Fs -  sampling frequency in Hz
        %  type - string to select synthesis type
        %         current options: 'fm', 'sine', or 'saw'
        
        % Copyright (c) 2009 Ken Schutte
        % more info at: http://www.kenschutte.com/midi
        
      N = floor(dur*Fs);
        
        if N == 0
          warning('Note with zero duration.');
          y = [];
          return;
        
        elseif N < 0
          warning('Note with negative duration. Skipping.');
          y = [];
          return;
        end
        
        n=0:N-1;
        if app.sinWave.Value == 1
          y = amp.*sin(2*pi*n*freq/Fs);
         elseif app.SquareWave.Value == 1
                  y = amp.* square(2*pi*freq/Fs*n);
         elseif app.SawWave.Value == 1
                  y = amp.* sawtooth(2*pi*freq/Fs*n);
        end


        % smooth edges w/ 10ms ramp
        if (dur > .02)
          L = 2*fix(.01*Fs)+1;  % L odd
          ramp = bartlett(L)';  % odd length
          L = ceil(L/2);
          y(1:L) = y(1:L) .* ramp(1:L);
          y(end-L+1:end) = y(end-L+1:end) .* ramp(end-L+1:end);
        end
    end
        
        % input= signal and Sample Rate, output= estimated frequency of the signal (using zero-crossing algorithm)
        function f = Zero_Crossing(app,x,Fs)
             index= find(diff(sign(x))); % contains the indecies where the zero crossing occured
             f=(length(index) * Fs / (2 * length(x))); % zero crossing occurs twice during each cycle 
        end
        
        % input= signal and Sample Rate, output= estimated frequency of the signal (using FFT)
        function f = FFT(app,x,Fs)
            % search for the max in |xdft| because x is real-valued
            [~,I] = max(abs(fft(x)));
            f=(I-1)*Fs/length(x);
        end

        function DetectNote(app,f0)
             app.freqValue.Text=num2str(f0);
             note_pos=1;
             note_oct=1;
             min_dif=inf;
             for i=1:12
                 for oct=1:8
                      dif= abs(f0-app.notes_freq(i)*(2^(oct-1)));
                      if min_dif>dif
                          min_dif=dif;
                          note_pos=i;
                          note_oct=oct;
                      end
                 end
             end
             output=strcat(app.notes_char(note_pos),num2str(note_oct));
             app.NoteChar.Text=output;   
        end
        
        function f = Autocorrelation(app,x,Fs)
            R=zeros(1,length(x));
            for m=1:length(x)
                for i=1:length(x)-m
                   R(m)=R(m)+x(i)*x(i+m);
                end
            end
            [peaks,locs] = findpeaks(R); 
            [~,peak1_ind]=max(peaks); 
            %find the period between 2 peaks
            if peak1_ind==1
                 period=locs(peak1_ind+1)-locs(peak1_ind); 
            else
                 period=locs(peak1_ind)-locs(peak1_ind-1); 
            end 
            f=Fs/period;
        end
        
        function f = Cross_Correlation(app,x,Fs)
            nfft = 2^nextpow2(2*length(x)-1); %define the size of the fft result
            r = ifft(fft(x,nfft) .* conj(fft(x,nfft))); % same as xcorr(x)
            [pks,locs] = findpeaks(r); 
            [~,peak1_ind]=max(pks); 
            %find the period between 2 peaks
            if peak1_ind==1
            period=locs(peak1_ind+1)-locs(peak1_ind); 
            else
                period=locs(peak1_ind)-locs(peak1_ind-1); 
            end
            f=Fs/period;
        end
        
        function f = AverageMagnitudeDifferential(app,x,Fs)
            AMDF=zeros(1,length(x));
            for m=1:length(x)
                for i=1:length(x)-m
                   AMDF(m)=AMDF(m)+abs(x(i)-x(i+m));
                end
            end 
            [pks,locs] = findpeaks(flipud(AMDF)); 
            [~,peak1_ind]=max(pks); 
            %find the period between 2 peaks
            if peak1_ind==1
            period=locs(peak1_ind+1)-locs(peak1_ind); 
            else
                period=locs(peak1_ind)-locs(peak1_ind-1); 
            end
            f=Fs/period;
        end
end
    

    methods (Access = private)

        % Button pushed function: A4
        function A4Pushed(app, event)
            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=440*(2^(a-4));
            PlayNote(app,freq);
        end

        % Callback function
        function C3Pushed(app, event)


        end

        % Callback function
        function GSharp3Pushed(app, event)
            
        end

        % Callback function
        function ASharp3Pushed(app, event)

            
        end

        % Callback function
        function FSharp5Pushed(app, event)

               
        end

        % Callback function
        function FSharp3Pushed(app, event)
            
        end

        % Button pushed function: ASharp4
        function ASharp4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=466.16*(2^(a-4));
            PlayNote(app,freq);             
        end

        % Callback function
        function DSharp5Pushed(app, event)
            
        end

        % Callback function
        function CSharp5Pushed(app, event)
           
        end

        % Button pushed function: GSharp4
        function GSharp4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=415.3*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: CSharp4
        function CSharp4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=277.18*(2^(a-4));
            PlayNote(app,freq);             
        end

        % Callback function
        function G3Pushed(app, event)
            
        end

        % Callback function
        function A3Pushed(app, event)
  
        end

        % Callback function
        function B3Pushed(app, event)
     
        end

        % Button pushed function: C4
        function C4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=261.625*(2^(a-4));
            PlayNote(app,freq);              
        end

        % Button pushed function: D4
        function D4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=293.66*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: E4
        function E4Pushed(app, event)
      
            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=329.63*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: F4
        function F4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=349.23*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: G4
        function G4Pushed(app, event)

              a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=392*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: B4
        function B4Pushed(app, event)

             a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=493.88*(2^(a-4));
            PlayNote(app,freq);
        end

        % Callback function
        function C5Pushed(app, event)
            
        end

        % Callback function
        function D5Pushed(app, event)
            
        end

        % Callback function
        function E5Pushed(app, event)
  
        end

        % Callback function
        function F5Pushed(app, event)
            
        end

        % Button pushed function: FSharp4
        function FSharp4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=369.99*(2^(a-4));
            PlayNote(app,freq);
        end

        % Button pushed function: DSharp4
        function DSharp4Pushed(app, event)

            a=round(app.frequencySlider.Value);
            app.frequencySlider.Value=a;
            freq=311.13*(2^(a-4));
            PlayNote(app,freq);
        end

        % Callback function
        function G5Pushed(app, event)
            
        end

        % Callback function
        function F3Pushed(app, event)
            
        end

        % Callback function
        function E3Pushed(app, event)
            
        end

        % Callback function
        function D3Pushed(app, event)
            
        end

        % Callback function
        function A5Pushed(app, event)
  
        end

        % Callback function
        function B5Pushed(app, event)
 
        end

        % Callback function
        function GSharp5Pushed(app, event)
   
        end

        % Callback function
        function ASharp5Pushed(app, event)
            
        end

        % Callback function
        function DSharp3Pushed(app, event)
    
        end

        % Callback function
        function CSharp3Pushed(app, event)
        
        end

        % Button pushed function: SwitchtoKeyboardButton
        function SwitchtoKeyboardButtonPushed(app, event)
            ch=0;
            while ch~=27
                ch =getkey;
                switch(ch)
                    case 122 % z -> A4
                         PlayNote(app,440);
                         
                     case 115 % s ->A#4
                         PlayNote(app,466.16);
                         
                     case 120 % x-> B4
                         PlayNote(app,493.88);
                         
                     case 99 % c-> C5
                         PlayNote(app,523.25);
                       
                     case 102 % f -> C#5
                         PlayNote(app,554.37);
                         
                     case 118 % v -> D5
                         PlayNote(app,587.33);
                        
                     case 103 % g -> D#5
                         PlayNote(app,622.25);
                         
                      case 98 % b -> E5
                         PlayNote(app,659.26);
                          
                      case 110 %n -> F5
                         PlayNote(app,698.46);
                        
                      case 106 % j -> F#5
                         PlayNote(app,739.99);
                       
                      case 109 % m -> G5
                         PlayNote(app,783.99);
                        
                      case 107 % k -> G#5
                         PlayNote(app,830.61);
                         
                      otherwise
                        break;
                end
            end
        end

        % Value changing function: attackKnob
        function attackKnobValueChanging(app, event)
            changingValue = event.Value;
            app.attackValue.Value=round(changingValue);
        end

        % Value changing function: decayKnob
        function decayKnobValueChanging(app, event)
            changingValue = event.Value;
            app.decayValue.Value=round(changingValue);
        end

        % Value changing function: sustainKnob
        function sustainKnobValueChanging(app, event)
            changingValue = event.Value;
            app.sustainValue.Value=round(changingValue);
        end

        % Value changing function: releaseKnob
        function releaseKnobValueChanging(app, event)
            changingValue = event.Value;
            app.releaseValue.Value=round(changingValue);
        end

        % Value changing function: attackKnob_2
        function attackKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.attackValue_2.Value=round(changingValue);
        end

        % Value changing function: decayKnob_2
        function decayKnob_2ValueChanging(app, event)
            changingValue = event.Value;
             app.decayValue_2.Value=round(changingValue);
        end

        % Value changing function: sustainKnob_2
        function sustainKnob_2ValueChanging(app, event)
            changingValue = event.Value;
             app.sustainValue_2.Value=round(changingValue);
        end

        % Value changing function: releaseKnob_2
        function releaseKnob_2ValueChanging(app, event)
            changingValue = event.Value;
             app.releaseValue_2.Value=round(changingValue);
        end

        % Value changed function: PLAYButton
        function PLAYButtonPushed(app, event)
            i=1;
            
            while app.record(1,i)~=0
                if app.PLAYButton.Value==1
                  app.PLAYButton.Text="STOP";
                else
                    app.PLAYButton.Text="PLAY";
                    return;
                end
                    PlayNote(app,app.record(1,i));
                    pause(app.time);
                    i=i+1;
            end
            app.PLAYButton.Text="PLAY";
            app.PLAYButton.Value=0;
        end

        % Value changed function: RECORDButton
        function RECORDButtonValueChanged(app, event)
            value = app.RECORDButton.Value;
            if value==0
                app.RECORDButton.Text="RECORD";
                app.noteIndex=1;
                return;
             end
             app.RECORDButton.Text="STOP";
             
             
        end

        % Value changing function: HighPassKnob
        function HighPassKnobValueChanging(app, event)
            changingValue = event.Value;
            app.highPass.Value=changingValue;
            if changingValue>0
                Wn=changingValue/app.sampleRate;
                n=round(app.highpassSlider.Value);
                app.highpassSlider.Value=n;
                [b,a] = butter(n,Wn,'high');
                [H,W]=freqz(b,a);
                plot(app.highpassAxes,W,H);
            end

        end

        % Value changing function: LowPassKnob
        function LowPassKnobValueChanging(app, event)
            changingValue = event.Value;
            app.lowPass.Value=changingValue;
            if changingValue>0
                Wn=changingValue/app.sampleRate;
                n=round(app.lowpassSlider.Value);
                app.lowpassSlider.Value=n;
                [b,a] = butter(n,Wn,'low');
                [H,W]=freqz(b,a);
                plot(app.lowpassAxes,W,H);
            end
        end

        % Button pushed function: PlayButton
        function PlaymidiButtonPushed(app, event)
           if app.MidiFileCheckBox.Value==0 
                f0=0;
                app.stop=0;
                [file,path] = uigetfile({'*.mp3';'*.wav'});
                if isequal(file,0)
                       return;
                end
                app.FileNameEditField.Value=file;
                [y,Fs] = audioread(fullfile(path,file));
                info=audioinfo(fullfile(path,file));
                SampleRate=info.SampleRate;
                yMono = sum(y, 2) / size(y, 2);
                yMono(yMono==0)=[];
                stp=floor(0.2*SampleRate);
                x=yMono;
                player = audioplayer(x, Fs);
                play(player);
                soundsc(x,Fs);
                plot(app.soundAxes,x);
                for j=1:stp:length(yMono)-stp+1
                     x=yMono(j:j+stp-1);
                     if app.AlgorithimDropDown.Value=="Zero-Crossing"
                         f0 = Zero_Crossing(app,x,SampleRate);
                     elseif app.AlgorithimDropDown.Value=="Fast Fourier Transform"
                         f0=FFT(app,x,SampleRate);
                     elseif app.AlgorithimDropDown.Value=="Autocorrelation"
                         f0=Autocorrelation(app,x,SampleRate);
                     elseif app.AlgorithimDropDown.Value=="Cross Correlation"
                         f0=Cross_Correlation(app,x,SampleRate);
                     elseif app.AlgorithimDropDown.Value=="Average Magnitude Differential"
                         f0=AverageMagnitudeDifferential(app,x,SampleRate);                 
                     end
                     DetectNote(app,f0);
                     pause(0.2);
                     if app.stop==1 
                         break;
                     end   
                end
           else
                [file,path] = uigetfile('*.mid');
                if isequal(file,0)
                       return;
                end
                app.FileNameEditField.Value=file;
                
                midi = readmidi(fullfile(path,file));
                [y,Fs]=midi2audio(app,midi);
            
                if app.highPass.Value>0
                    Wn=app.highPass.Value /Fs;
                    n=round(app.highpassSlider.Value);
                    app.highpassSlider.Value=n;
                    [b,a] = butter(n,Wn,'high');
                    y=filter(b,a,y);
                    [H,W]=freqz(b,a);
                    plot(app.highpassAxes,W,H);
                end
                if app.lowPass.Value>0
                    Wn=app.lowPass.Value /Fs;
                    n=round(app.lowpassSlider.Value);
                    app.lowpassSlider.Value=n;
                    [b,a] = butter(n,Wn,'low');
                    y=filter(b,a,y);
                    [H,W]=freqz(b,a);
                    plot(app.lowpassAxes,W,H);
                end
                soundsc(y,Fs);
                if app.AnimatedCheckBox.Value== 1
                         dur=1:2.5:length(y);
                         animate(app,dur,y);
                else
                    dur=1:1:length(y);
                    plot(app.soundAxes,dur,y);
                end
           end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            clear sound;
            app.stop=1;
            cla(app.soundAxes);
        end

        % Value changing function: highpassSlider
        function highpassSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.highPass.Value>0 
                Wn=app.highPass.Value/app.sampleRate;
                n=round(changingValue);
                app.highpassSlider.Value=n;
                [b,a] = butter(n,Wn,'high');
                [H,W]=freqz(b,a);
                plot(app.highpassAxes,W,H);
            end
        end

        % Value changing function: lowpassSlider
        function lowpassSliderValueChanging(app, event)
            changingValue = event.Value;
            if  app.lowPass.Value>0
                Wn=app.lowPass.Value/app.sampleRate;
                n=round(changingValue);
                app.lowpassSlider.Value=n;
                [b,a] = butter(n,Wn,'low');
                [H,W]=freqz(b,a);
                plot(app.lowpassAxes,W,H);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PianoUIFigure = uifigure;
            app.PianoUIFigure.Name = 'Piano';
            app.C4 = uibutton(app.PianoUIFigure, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @C4Pushed, true);
            app.C4.Text = {'C'; ''};
            app.D4 = uibutton(app.PianoUIFigure, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @D4Pushed, true);
            app.D4.Text = {'D'; ''};
            app.E4 = uibutton(app.PianoUIFigure, 'push');
            app.E4.ButtonPushedFcn = createCallbackFcn(app, @E4Pushed, true);
            app.E4.Text = 'E';
            app.F4 = uibutton(app.PianoUIFigure, 'push');
            app.F4.ButtonPushedFcn = createCallbackFcn(app, @F4Pushed, true);
            app.F4.Text = 'F';
            app.G4 = uibutton(app.PianoUIFigure, 'push');
            app.G4.ButtonPushedFcn = createCallbackFcn(app, @G4Pushed, true);
            app.G4.Text = 'G';
            app.A4 = uibutton(app.PianoUIFigure, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @A4Pushed, true);
            app.A4.Text = 'A';
            app.B4 = uibutton(app.PianoUIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4Pushed, true);
            app.B4.Text = 'B';
            app.FSharp4 = uibutton(app.PianoUIFigure, 'push');
            app.FSharp4.ButtonPushedFcn = createCallbackFcn(app, @FSharp4Pushed, true);
            app.FSharp4.Text = 'F#';
            app.ASharp4 = uibutton(app.PianoUIFigure, 'push');
            app.ASharp4.ButtonPushedFcn = createCallbackFcn(app, @ASharp4Pushed, true);
            app.ASharp4.Text = 'A#';
            app.GSharp4 = uibutton(app.PianoUIFigure, 'push');
            app.GSharp4.ButtonPushedFcn = createCallbackFcn(app, @GSharp4Pushed, true);
            app.GSharp4.Text = 'G#';
            app.DSharp4 = uibutton(app.PianoUIFigure, 'push');
            app.DSharp4.ButtonPushedFcn = createCallbackFcn(app, @DSharp4Pushed, true);
            app.DSharp4.Text = 'D#';
            app.CSharp4 = uibutton(app.PianoUIFigure, 'push');
            app.CSharp4.ButtonPushedFcn = createCallbackFcn(app, @CSharp4Pushed, true);
            app.CSharp4.Text = 'C#';
            app.soundAxes = uiaxes(app.PianoUIFigure);
            title(app.soundAxes, '')
            xlabel(app.soundAxes, '')
            ylabel(app.soundAxes, '')
            app.frequencySlider = uislider(app.PianoUIFigure);
            app.frequencySlider.Limits = [2 7];
            app.frequencySlider.MajorTicks = [2 3 4 5 6 7];
            app.frequencySlider.MinorTicks = 1;
            app.frequencySlider.Value = 4;
            app.envelopeAxes = uiaxes(app.PianoUIFigure);
            title(app.envelopeAxes, '')
            xlabel(app.envelopeAxes, '')
            ylabel(app.envelopeAxes, '')
            app.FrequencyHzLabel = uilabel(app.PianoUIFigure);
            app.FrequencyHzLabel.Text = 'Frequency(Hz):';
            app.freqValue = uilabel(app.PianoUIFigure);
            app.freqValue.Text = '00000';
            app.AttackEditFieldLabel = uilabel(app.PianoUIFigure);
            app.AttackEditFieldLabel.Text = 'Attack';
            app.attackValue = uieditfield(app.PianoUIFigure, 'numeric');
            app.attackValue.Limits = [0 100];
            app.attackValue.Value = 10;
            app.DecayEditFieldLabel = uilabel(app.PianoUIFigure);
            app.DecayEditFieldLabel.Text = 'Decay';
            app.decayValue = uieditfield(app.PianoUIFigure, 'numeric');
            app.decayValue.Limits = [0 100];
            app.decayValue.Value = 15;
            app.SustainEditFieldLabel = uilabel(app.PianoUIFigure);
            app.SustainEditFieldLabel.Text = 'Sustain';
            app.sustainValue = uieditfield(app.PianoUIFigure, 'numeric');
            app.sustainValue.Limits = [0 100];
            app.sustainValue.Value = 60;
            app.ReleaseEditFieldLabel = uilabel(app.PianoUIFigure);
            app.ReleaseEditFieldLabel.Text = 'Release';
            app.releaseValue = uieditfield(app.PianoUIFigure, 'numeric');
            app.releaseValue.Limits = [0 100];
            app.releaseValue.Value = 15;
            app.Label_2 = uilabel(app.PianoUIFigure);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.PianoUIFigure);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.PianoUIFigure);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.PianoUIFigure);
            app.Label_5.Text = '%';
            app.AmplitudeADSRenvelopeControlerLabel = uilabel(app.PianoUIFigure);
            app.AmplitudeADSRenvelopeControlerLabel.Text = 'Amplitude ADSR envelope Controler';
            app.SwitchtoKeyboardButton = uibutton(app.PianoUIFigure, 'push');
            app.SwitchtoKeyboardButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchtoKeyboardButtonPushed, true);
            app.SwitchtoKeyboardButton.Text = {'Switch to Keyboard'; ''};
            app.NoteButtonGroup = uibuttongroup(app.PianoUIFigure);
            app.NoteButtonGroup.Title = 'Note';
            app.NoteButton = uiradiobutton(app.NoteButtonGroup);
            app.NoteButton.Text = '1/4 Note';
            app.NoteButton.Value = true;
            app.NoteButton_2 = uiradiobutton(app.NoteButtonGroup);
            app.NoteButton_2.Text = {'1/2 Note'; ''};
            app.NoteButton_3 = uiradiobutton(app.NoteButtonGroup);
            app.NoteButton_3.Text = {'1 Note'; ''};
            app.envelopeAxes_2 = uiaxes(app.PianoUIFigure);
            title(app.envelopeAxes_2, '')
            xlabel(app.envelopeAxes_2, '')
            ylabel(app.envelopeAxes_2, '')
            app.Label_6 = uilabel(app.PianoUIFigure);
            app.Label_6.Text = '%';
            app.Label_7 = uilabel(app.PianoUIFigure);
            app.Label_7.Text = '%';
            app.Label_8 = uilabel(app.PianoUIFigure);
            app.Label_8.Text = '%';
            app.Label_9 = uilabel(app.PianoUIFigure);
            app.Label_9.Text = '%';
            app.attackValue_2 = uieditfield(app.PianoUIFigure, 'numeric');
            app.attackValue_2.Limits = [0 100];
            app.decayValue_2 = uieditfield(app.PianoUIFigure, 'numeric');
            app.decayValue_2.Limits = [0 100];
            app.sustainValue_2 = uieditfield(app.PianoUIFigure, 'numeric');
            app.sustainValue_2.Limits = [0 100];
            app.sustainValue_2.Value = 100;
            app.releaseValue_2 = uieditfield(app.PianoUIFigure, 'numeric');
            app.releaseValue_2.Limits = [0 100];
            app.FrequencyADSRenvelopeControlerLabel = uilabel(app.PianoUIFigure);
            app.FrequencyADSRenvelopeControlerLabel.Text = 'Frequency ADSR envelope Controler';
            app.attackKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.attackKnob.ValueChangingFcn = createCallbackFcn(app, @attackKnobValueChanging, true);
            app.attackKnob.Value = 10;
            app.decayKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.decayKnob.ValueChangingFcn = createCallbackFcn(app, @decayKnobValueChanging, true);
            app.decayKnob.Value = 15;
            app.sustainKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.sustainKnob.ValueChangingFcn = createCallbackFcn(app, @sustainKnobValueChanging, true);
            app.sustainKnob.Value = 60;
            app.releaseKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.releaseKnob.ValueChangingFcn = createCallbackFcn(app, @releaseKnobValueChanging, true);
            app.releaseKnob.Value = 15;
            app.attackKnob_2 = uiknob(app.PianoUIFigure, 'continuous');
            app.attackKnob_2.ValueChangingFcn = createCallbackFcn(app, @attackKnob_2ValueChanging, true);
            app.decayKnob_2 = uiknob(app.PianoUIFigure, 'continuous');
            app.decayKnob_2.ValueChangingFcn = createCallbackFcn(app, @decayKnob_2ValueChanging, true);
            app.sustainKnob_2 = uiknob(app.PianoUIFigure, 'continuous');
            app.sustainKnob_2.ValueChangingFcn = createCallbackFcn(app, @sustainKnob_2ValueChanging, true);
            app.sustainKnob_2.Value = 100;
            app.releaseKnob_2 = uiknob(app.PianoUIFigure, 'continuous');
            app.releaseKnob_2.ValueChangingFcn = createCallbackFcn(app, @releaseKnob_2ValueChanging, true);
            app.AttackEditFieldLabel_3 = uilabel(app.PianoUIFigure);
            app.AttackEditFieldLabel_3.Text = 'Attack';
            app.DecayEditFieldLabel_3 = uilabel(app.PianoUIFigure);
            app.DecayEditFieldLabel_3.Text = 'Decay';
            app.SustainEditFieldLabel_3 = uilabel(app.PianoUIFigure);
            app.SustainEditFieldLabel_3.Text = 'Sustain';
            app.ReleaseEditFieldLabel_3 = uilabel(app.PianoUIFigure);
            app.ReleaseEditFieldLabel_3.Text = 'Release';
            app.AnimatedCheckBox = uicheckbox(app.PianoUIFigure);
            app.AnimatedCheckBox.Text = 'Animated';
            app.AnimatedCheckBox.Value = true;
            app.RECORDButton = uibutton(app.PianoUIFigure, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonValueChanged, true);
            app.RECORDButton.Text = 'RECORD';
            app.HighPassKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.HighPassKnob.Limits = [0 4000];
            app.HighPassKnob.ValueChangingFcn = createCallbackFcn(app, @HighPassKnobValueChanging, true);
            app.HighPassKnob.Value = 10;
            app.LowPassKnob = uiknob(app.PianoUIFigure, 'continuous');
            app.LowPassKnob.Limits = [0 4000];
            app.LowPassKnob.ValueChangingFcn = createCallbackFcn(app, @LowPassKnobValueChanging, true);
            app.LowPassKnob.Value = 15;
            app.highPass = uieditfield(app.PianoUIFigure, 'numeric');
            app.highPass.Limits = [0 Inf];
            app.lowPass = uieditfield(app.PianoUIFigure, 'numeric');
            app.lowPass.Limits = [0 Inf];
            app.HzLabel = uilabel(app.PianoUIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.PianoUIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.lolabel = uilabel(app.PianoUIFigure);
            app.lolabel.Text = 'Low-pass';
            app.hilabel = uilabel(app.PianoUIFigure);
            app.hilabel.Text = 'High-pass';
            app.WaveButtonGroup = uibuttongroup(app.PianoUIFigure);
            app.WaveButtonGroup.Title = 'Wave';
            app.sinWave = uiradiobutton(app.WaveButtonGroup);
            app.sinWave.Text = 'Sin Wave';
            app.sinWave.Value = true;
            app.SawWave = uiradiobutton(app.WaveButtonGroup);
            app.SawWave.Text = 'Saw Wave';
            app.SquareWave = uiradiobutton(app.WaveButtonGroup);
            app.SquareWave.Text = 'Square Wave';
            app.FileNameEditFieldLabel = uilabel(app.PianoUIFigure);
            app.FileNameEditFieldLabel.Text = {'File Name:'; ''};
            app.FileNameEditField = uieditfield(app.PianoUIFigure, 'text');
            app.PLAYButton = uibutton(app.PianoUIFigure, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = {'PLAY'; ''};
            app.highpassSlider = uislider(app.PianoUIFigure);
            app.highpassSlider.Limits = [1 6];
            app.highpassSlider.MajorTicks = [1 2 3 4 5 6];
            app.highpassSlider.ValueChangingFcn = createCallbackFcn(app, @highpassSliderValueChanging, true);
            app.highpassSlider.MinorTicks = 1;
            app.highpassSlider.Value = 1;
            app.lowpassSlider = uislider(app.PianoUIFigure);
            app.lowpassSlider.Limits = [1 6];
            app.lowpassSlider.MajorTicks = [1 2 3 4 5 6];
            app.lowpassSlider.ValueChangingFcn = createCallbackFcn(app, @lowpassSliderValueChanging, true);
            app.lowpassSlider.MinorTicks = 1;
            app.lowpassSlider.Value = 1;
            app.highpassAxes = uiaxes(app.PianoUIFigure);
            title(app.highpassAxes, '')
            xlabel(app.highpassAxes, '')
            ylabel(app.highpassAxes, '')
            app.lowpassAxes = uiaxes(app.PianoUIFigure);
            title(app.lowpassAxes, '')
            xlabel(app.lowpassAxes, '')
            ylabel(app.lowpassAxes, '')
            app.stopButton = uibutton(app.PianoUIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.PlayButton = uibutton(app.PianoUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlaymidiButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PitchDetectionLabel = uilabel(app.PianoUIFigure);
            app.PitchDetectionLabel.Text = 'Pitch Detection';
            app.NoteLabel = uilabel(app.PianoUIFigure);
            app.NoteLabel.Text = 'Note:';
            app.NoteChar = uilabel(app.PianoUIFigure);
            app.NoteChar.Text = 'A4';
            app.AlgorithimLabel = uilabel(app.PianoUIFigure);
            app.AlgorithimLabel.Text = 'Algorithim:';
            app.AlgorithimDropDown = uidropdown(app.PianoUIFigure);
            app.AlgorithimDropDown.Items = {'Zero-Crossing', 'Cross Correlation', 'Average Magnitude Differential', 'Autocorrelation', 'Fast Fourier Transform'};
            app.AlgorithimDropDown.Value = 'Zero-Crossing';
            app.EchoCheckBox = uicheckbox(app.PianoUIFigure);
            app.EchoCheckBox.Text = 'Echo';
            app.alphaSpinnerLabel = uilabel(app.PianoUIFigure);
            app.alphaSpinnerLabel.Text = 'alpha';
            app.alphaSpinner = uispinner(app.PianoUIFigure);
            app.alphaSpinner.Step = 0.01;
            app.alphaSpinner.Limits = [0 1];
            app.alphaSpinner.Value = 0.5;
            app.MidiFileCheckBox = uicheckbox(app.PianoUIFigure);
            app.MidiFileCheckBox.Text = 'Midi File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = piano1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PianoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PianoUIFigure)
        end
    end
end